classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MainPanel                     matlab.ui.container.Panel
        Tabs                          matlab.ui.container.TabGroup
        Data                          matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        NextdataselectionButtonGroup  matlab.ui.container.ButtonGroup
        HealthyButton                 matlab.ui.control.RadioButton
        CerebralPalsyButton           matlab.ui.control.RadioButton
        Graphs                        matlab.ui.container.Tab
        GraphsPanel                   matlab.ui.container.Panel
        UIAxesBefore                  matlab.ui.control.UIAxes
        UIAxesAfter                   matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        PlotoptionsTab                matlab.ui.container.Tab
        SelectdataLabel               matlab.ui.control.Label
        HealthyCheckBox               matlab.ui.control.CheckBox
        CerebralPalsyCheckBox         matlab.ui.control.CheckBox
        FourieroptionsTab             matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        WindowDropDownLabel           matlab.ui.control.Label
        WindowDropDown                matlab.ui.control.DropDown
        DataoptionsTab                matlab.ui.container.Tab
        SmoothingLabel                matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        AmountSliderLabel             matlab.ui.control.Label
        AmountSlider                  matlab.ui.control.Slider
        openFilePanel                 matlab.ui.container.Panel
        OpenfileButton                matlab.ui.control.Button
        CurrentfileEditFieldLabel     matlab.ui.control.Label
        CurrentfileEditField          matlab.ui.control.EditField
        HelpButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        healthyData         % Contains the data of a healthy child
        cerebralPalsyData   % Contains the data of a child w/ cerebral palsy
        HelpPopup           %Help window
    end
    
    methods (Access = private)
        
        function showData(app)
            if app.HealthyCheckBox.Value == 1 %If healthy is on: draw healthy data
                plot(app.UIAxesBefore, app.healthyData);
                if app.CerebralPalsyCheckBox == 1 %If other data needs to be plotted, hold on
                    hold on;
                end
            end
            
            if app.CerebralPalsyCheckBox.Value == 1
                plot(app.UIAxesBefore, app.cerebralPalsyData);
                if app.HealthyCheckBox == 1 %Hold off if hold on was used before
                    hold off;
                end
            end
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            warning('OFF', 'MATLAB:table:ModifiedAndSavedVarnames');
            [file, path] = uigetfile('*.xlsx');
            if isequal(file,0)          
                uialert(app.UIFigure, "Please input an Excel file.","Notification");
            else
                t = readtable(fullfile(path, file));
                app.UITable.Data = t;
            end
            fullpathname = strcat(path, file);
            app.CurrentfileEditField.Value = fullpathname;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            selectedCells = event.Indices;
            [m,n] = size( selectedCells );
            firstRow    = selectedCells(1,1);   lastRow     = selectedCells(m,1);
            firstColumn = selectedCells(1,n);   lastColumn  = selectedCells(m,n);
            
            if firstColumn == lastColumn
                selectedData = app.UITable.Data(firstRow:lastRow,firstColumn);
                if app.HealthyButton.Value == 1
                    app.healthyData(:,1) = selectedData{:,:};
                    showData(app);
                else 
                    app.cerebralPalsyData(:,1) = selectedData{:,:};
                    showData(app);
                end
            else
                uialert(app.UIFigure, "Please only select one column! Changes have not been saved.","Notification");
            end
        end

        % Value changed function: HealthyCheckBox
        function HealthyCheckBoxValueChanged(app, event)
            showData(app);
        end

        % Value changed function: CerebralPalsyCheckBox
        function CerebralPalsyCheckBoxValueChanged(app, event)
            showData(app);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
             % Disable Plot Options button while dialog is open
            app.HelpButton.Enable = 'off';           
            % Open the options dialog and pass inputs
            app.HelpPopup = Help(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.healthyData = smoothdata( app.healthyData,'sgolay',value);
            app.cerebralPalsyData = smoothdata(app.cerebralPalsyData ,'sgolay',value);
            showData(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainPanel = uipanel(app.UIFigure);
            app.Tabs = uitabgroup(app.MainPanel);
            app.Data = uitab(app.Tabs);
            app.Data.Title = 'Data';
            app.UITable = uitable(app.Data);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'Here you can select the data you want to consider'};
            app.NextdataselectionButtonGroup = uibuttongroup(app.Data);
            app.NextdataselectionButtonGroup.Title = 'Next data selection:';
            app.HealthyButton = uiradiobutton(app.NextdataselectionButtonGroup);
            app.HealthyButton.Text = 'Healthy';
            app.HealthyButton.Value = true;
            app.CerebralPalsyButton = uiradiobutton(app.NextdataselectionButtonGroup);
            app.CerebralPalsyButton.Text = 'Cerebral Palsy';
            app.Graphs = uitab(app.Tabs);
            app.Graphs.Title = 'Graphs';
            app.GraphsPanel = uipanel(app.Graphs);
            app.UIAxesBefore = uiaxes(app.GraphsPanel);
            title(app.UIAxesBefore, 'Original data')
            xlabel(app.UIAxesBefore, 'X')
            ylabel(app.UIAxesBefore, 'Y')
            app.UIAxesAfter = uiaxes(app.GraphsPanel);
            title(app.UIAxesAfter, 'Adjusted data')
            xlabel(app.UIAxesAfter, 'X')
            ylabel(app.UIAxesAfter, 'Y')
            app.TabGroup = uitabgroup(app.Graphs);
            app.PlotoptionsTab = uitab(app.TabGroup);
            app.PlotoptionsTab.Title = 'Plot options';
            app.SelectdataLabel = uilabel(app.PlotoptionsTab);
            app.SelectdataLabel.Text = 'Select data';
            app.HealthyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.HealthyCheckBox.ValueChangedFcn = createCallbackFcn(app, @HealthyCheckBoxValueChanged, true);
            app.HealthyCheckBox.Text = 'Healthy';
            app.CerebralPalsyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.CerebralPalsyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CerebralPalsyCheckBoxValueChanged, true);
            app.CerebralPalsyCheckBox.Text = 'Cerebral Palsy';
            app.FourieroptionsTab = uitab(app.TabGroup);
            app.FourieroptionsTab.Title = 'Fourier options';
            app.Label = uilabel(app.FourieroptionsTab);
            app.Label.Text = '';
            app.WindowDropDownLabel = uilabel(app.FourieroptionsTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.FourieroptionsTab);
            app.DataoptionsTab = uitab(app.TabGroup);
            app.DataoptionsTab.Title = 'Data options';
            app.SmoothingLabel = uilabel(app.DataoptionsTab);
            app.SmoothingLabel.Text = 'Smoothing';
            app.Switch = uiswitch(app.DataoptionsTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.AmountSliderLabel = uilabel(app.DataoptionsTab);
            app.AmountSliderLabel.Text = 'Amount';
            app.AmountSlider = uislider(app.DataoptionsTab);
            app.AmountSlider.MajorTicks = [0 50 100];
            app.AmountSlider.MajorTickLabels = {'0', '50', '100'};
            app.AmountSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.openFilePanel = uipanel(app.MainPanel);
            app.OpenfileButton = uibutton(app.openFilePanel, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
            app.CurrentfileEditFieldLabel = uilabel(app.openFilePanel);
            app.CurrentfileEditFieldLabel.Text = 'Current file:';
            app.CurrentfileEditField = uieditfield(app.openFilePanel, 'text');
            app.CurrentfileEditField.Editable = 'off';
            app.HelpButton = uibutton(app.openFilePanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end